classdef LAB3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DialogApp  % Selection menu
        Cnum % Color number
        Mnum % Marker number
    end
    
    methods (Access = private)
        
        function UpdGr(app)
            x = 0:0.1:10;
            y = sin(x);
            color='b';
            marker='s';
            if app.Cnum == 1
                color='r';
            end
            if app.Mnum ==1
                marker='none';
            elseif app.Mnum ==2
                marker='o';
            end
            plot(app.UIAxes,x,y,'Color',color,'Marker',marker);
        end
    end
    
    methods (Access = public)
        
        function returnMandC(app,M,C)
            app.Mnum=M;
            app.Cnum=C;
            UpdGr(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Cnum = 1;
            app.Mnum = 1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            UpdGr(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app.DialogApp)
                delete(app)
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4.Enable = 'off';
            app.DialogApp = LAB3_1(app,app.Mnum,app.Cnum);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'График')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Очистить';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Выход';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Параметры';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end