classdef LAB3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_6       matlab.ui.control.Button
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_5       matlab.ui.control.RadioButton
        Button_4       matlab.ui.control.RadioButton
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_3       matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.RadioButton
        Button         matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, marker, color)
            app.CallingApp = main;
            if marker==1
                app.Button.Value=1;
            elseif marker==2
                app.Button_2.Value=1;
            else % marker==3
                app.Button_3.Value=1;
            end
            if color==1
                app.Button_4.Value=1;
            else % color==2
                app.Button_5.Value=1;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Button_4.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.CallingApp.Button_4.Enable = 'on';
            m=3;
            c=2;
            
            if app.Button.Value==1
                m=1;
            elseif app.Button_2.Value==1
                m=2;
            end
            
            if app.Button_4.Value==1
                c=1;
            end
            
            returnMandC(app.CallingApp,m,c)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Маркер';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Нет';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Круг';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'Квадрат';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = 'Цвет';
            app.Button_4 = uiradiobutton(app.ButtonGroup_2);
            app.Button_4.Text = 'Красный';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup_2);
            app.Button_5.Text = {'Синий'; ''};
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = 'Применить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB3_1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end