classdef LAB4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        VOLUMESlider       matlab.ui.control.Slider
        VOLUMESliderLabel  matlab.ui.control.Label
        Label              matlab.ui.control.Label
        PLAYButton         matlab.ui.control.Button
        STOPButton         matlab.ui.control.Button
        RESUMEButton       matlab.ui.control.Button
        PAUSEButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        SOUND % nomera
        FS
        Ydefault
        Y
        VOLUME
        myTimer 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            app.PLAYButton.Enable=0;
            app.STOPButton.Enable=1;
            app.PAUSEButton.Enable=1;
            app.RESUMEButton.Enable=0;
            
            [app.Y,app.FS] = audioread('sound.wav');
            app.VOLUME=app.VOLUMESlider.Value;
            app.SOUND = audioplayer(app.Y.*(app.VOLUME/5),app.FS);
            play(app.SOUND);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.PLAYButton.Enable=1;
            app.STOPButton.Enable=0;
            app.PAUSEButton.Enable=0;
            app.RESUMEButton.Enable=0;
            
            app.VOLUMEGauge.Enable=1;
            
            stop(app.SOUND);
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            app.PAUSEButton.Enable=1;
            app.RESUMEButton.Enable=0;
            
            resume(app.SOUND);
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            app.PAUSEButton.Enable=0;
            app.RESUMEButton.Enable=1;
            
            pause(app.SOUND);
        end

        % Callback function
        function VOLUMESpinnerValueChanged(app, event)

        end

        % Callback function
        function VOLUMESpinnerValueChanging(app, event)

        end

        % Value changed function: VOLUMESlider
        function VOLUMESliderValueChanged(app, event)
            if not(app.VOLUME==app.VOLUMESlider.Value)
                app.VOLUME = app.VOLUMESlider.Value;
                pause(app.SOUND);
                a=get(app.SOUND,'CurrentSample');
                b=get(app.SOUND,'TotalSamples');
                stop(app.SOUND);
                app.SOUND = audioplayer(app.Y(a:b,:).*(app.VOLUME/40),app.FS);
                if not(app.PAUSEButton.Enable==0)
                    play(app.SOUND);
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PAUSEButton = uibutton(app.UIFigure, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Enable = 'off';
            app.PAUSEButton.Text = 'PAUSE';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Enable = 'off';
            app.RESUMEButton.Text = 'RESUME';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'НОМЕРА';
            app.VOLUMESliderLabel = uilabel(app.UIFigure);
            app.VOLUMESliderLabel.Text = 'VOLUME';
            app.VOLUMESlider = uislider(app.UIFigure);
            app.VOLUMESlider.ValueChangedFcn = createCallbackFcn(app, @VOLUMESliderValueChanged, true);
            app.VOLUMESlider.Value = 40;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end